classdef Choose_Track_Huellkurve < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        Label_3                       matlab.ui.control.Label
        Label_2                       matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        SourceDropDown                matlab.ui.control.DropDown
        SourceDropDownLabel           matlab.ui.control.Label
        SelectTrackLabel              matlab.ui.control.Label
        AbzweigButtonGroup            matlab.ui.container.ButtonGroup
        Abzweig3Button                matlab.ui.control.ToggleButton
        Abzweig2Button                matlab.ui.control.ToggleButton
        Abzweig1Button                matlab.ui.control.ToggleButton
        LayerAuswahlListBox           matlab.ui.control.ListBox
        LayerAuswahlListBoxLabel      matlab.ui.control.Label
        DateipfadEditField            matlab.ui.control.EditField
        DateipfadEditFieldLabel       matlab.ui.control.Label
        ZwischenstandspeichernButton  matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        

    end
    
    properties (Access = public)
        Layout;
        Layer;
        Fahrkurs;
    end
    
    methods (Access = public)
        
%         function results = DXFtool(app)
%             %dxf=DXFtool ('Layout_1.dxf')
%             app.UIAxes.value=DXFtool ('Layout_1.dxf');
%             
%             
%         end


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            
            if app.SourceDropDown.Value == 'ANS+ Import'
                [file,path]=uigetfile('*.mat') ;

            else if app.SourceDropDown.Value == 'CAD Import'
            [file,path]=uigetfile('*.dxf') ;
            
            end
            % Dateipfad ausgeben
            app.DateipfadEditField.Value= [path,file];
            

            dxf= DXFtool(file);
            app.Layout=dxf;
            clf(app.UIAxes);

            %Nach moeglichen Layer durchsuchen
            Layer_Halle =possibleLayers(dxf);
            app.Layer= Layer_Halle;

            %Moegliche Layer in Listbox anzeigen
            app.LayerAuswahlListBox.Items = app.Layer;


        end

        % Value changing function: DateipfadEditField
        function DateipfadEditFieldValueChanging(app, event)
            changingValue = event.Value;


            DateipfadEditFieldValueChanging.Value= fullfile(path,file) 
            %disp[file, path];
        end

        % Value changed function: LayerAuswahlListBox
        function LayerAuswahlListBoxValueChanged(app, event)
            value = app.LayerAuswahlListBox.Value;
           

            hold (app.UIAxes, 'off');
            gewaehlter_Layer= app.LayerAuswahlListBox.Value;
            gewaehlter_Layer= convertCharsToStrings(gewaehlter_Layer);


            Layer_neu=selectLayer(app.Layout, gewaehlter_Layer);
            m= length(Layer_neu.entities);
            Layer_Punkte= transform(Layer_neu,m);


            n = 100;
            for i = 1:length(Layer_Punkte)
                [xq ,yq] = bezier3(Layer_Punkte{1,i}.x, Layer_Punkte{1,i}.y,n);
                plot(app.UIAxes, xq, yq,'k');
                hold (app.UIAxes, 'on');
            end


%Sortierte_Punkte= Bezier_Reihenfolge(Layer_Punkte, length(Layer_Punkte));


%_________________Sortieren der Reihenfolge 

bezier_unsortiert= Layer_Punkte;
size=length(Layer_Punkte);
Startpunkt{1,1}=  bezier_unsortiert{1,1};                   %Eigentlich der Startpunkt, wird spaeter weiterverwendert als Zwischenpunkt
Zwischenpunkt{1,1}= Startpunkt{1,1};
bezier_sortiert=[];                                         %cell(1,size);            %Initalisierung des Feldes
bezier_sortiert{1,1}= bezier_unsortiert{1,1};

f=1;

for k=1:size
    Hilfsfeld={};                   %Speicher bei mehreren moeglichen Wegen bzw. Abzeigen
    noFound=0;
    speicher={};

    %if ~isempty(bezier_unsortiert{1,k})

    for i=2:size            % Abgleich des Zwischenpunktes mit allen Punkten


        befuellt=isempty(bezier_unsortiert{1,i}.x);                           %Überprüfen ob der Eintrag Werte gespeichert hat
        if befuellt == 0                                                    %Leere Arrays und damit breits benutzte werden nicht weiter betrachten

            l_z=length(Zwischenpunkt{1,1}.x);
            l_p=length(bezier_unsortiert{1,i}.x);
            %Absolutwerte der Differenzen der Punkte berechnen
            absolut_1_Anfangspunkt= abs(bezier_unsortiert{1,i}.x(1)- Zwischenpunkt{1,1}.x(l_z));
            absolut_2_Anfangspunkt= abs(bezier_unsortiert{1,i}.y(1)- Zwischenpunkt{1,1}.y(l_z));

            absolut_1_Endpunkt= abs(bezier_unsortiert{1,i}.x(l_p) - Zwischenpunkt{1,1}.x(l_z));
            absolut_2_Endpunkt= abs(bezier_unsortiert{1,i}.y(l_p) - Zwischenpunkt{1,1}.y(l_z));

            if (absolut_1_Anfangspunkt <= 0.1) && (absolut_2_Anfangspunkt < 0.1)
                    Hilfsfeld{1,noFound+1}.x=bezier_unsortiert{1,i}.x;
                    Hilfsfeld{1,noFound+1}.y=bezier_unsortiert{1,i}.y;
                    zaehler= noFound+1;
                    speicher{zaehler}=i;
                    noFound=noFound+1;

                elseif (absolut_1_Endpunkt <= 0.1) && (absolut_2_Endpunkt < 0.1)
                    if length(bezier_unsortiert{1,i}.x) == 3
                        Hilfsfeld{1,noFound+1}.x(1)=bezier_unsortiert{1,i}.x(l_p);
                        Hilfsfeld{1,noFound+1}.y(1)=bezier_unsortiert{1,i}.y(l_p);
                        Hilfsfeld{1,noFound+1}.x(2)=bezier_unsortiert{1,i}.x(2);
                        Hilfsfeld{1,noFound+1}.y(2)=bezier_unsortiert{1,i}.y(2);
                        Hilfsfeld{1,noFound+1}.x(3)=bezier_unsortiert{1,i}.x(1);
                        Hilfsfeld{1,noFound+1}.y(3)=bezier_unsortiert{1,i}.y(1);
                        zaehler= noFound+1;
                        speicher{zaehler}=i;
                        noFound=noFound+1;

                    elseif length(bezier_unsortiert{1,i}.x) == 4
                        Hilfsfeld{1,noFound+1}.x(1)=bezier_unsortiert{1,i}.x(4);
                        Hilfsfeld{1,noFound+1}.y(1)=bezier_unsortiert{1,i}.y(4);
                        Hilfsfeld{1,noFound+1}.x(2)=bezier_unsortiert{1,i}.x(3);
                        Hilfsfeld{1,noFound+1}.y(2)=bezier_unsortiert{1,i}.y(3);
                        Hilfsfeld{1,noFound+1}.x(3)=bezier_unsortiert{1,i}.x(2);
                        Hilfsfeld{1,noFound+1}.y(3)=bezier_unsortiert{1,i}.y(2);
                        Hilfsfeld{1,noFound+1}.x(4)=bezier_unsortiert{1,i}.x(1);
                        Hilfsfeld{1,noFound+1}.y(4)=bezier_unsortiert{1,i}.y(1);
                        zaehler= noFound+1;
                        speicher{zaehler}=i;
                        noFound=noFound+1;
                    end
                end
            end
        end
    end


    % Ueberpruefung der moeglichen Abzweige
    if noFound==1
       bezier_sortiert{1,k+1}.x=Hilfsfeld{1,1}.x;
        bezier_sortiert{1,k+1}.y=Hilfsfeld{1,1}.y;
        Zwischenpunkt{1,1}.x=[];
        Zwischenpunkt{1,1}.y=[];
        Zwischenpunkt{1,1}= bezier_sortiert{1,k+1};
        eintrag= speicher{noFound};
        bezier_unsortiert{1,eintrag}.x=[];
        bezier_unsortiert{1,eintrag}.y=[];

    elseif noFound>1
        %str=['Welcher Abzweig soll gewählt werden? Es gibt ' , num2str(noFound)];
        %prompt =  str;
        %abzweig = input(prompt);
        %plot(app.UIAxes, Hilfsfeld{1}.x, Hilfsfeld{1}.y, "Color",'b');  %Evtl. mit schleife und noFound unf unterschiedliche Farben über Array 
        %plot(app.UIAxes, Hilfsfeld{2}.x, Hilfsfeld{2}.y, "Color",'m');
        farben_Abzweige= ['b'; 'm'; 'g'];
        n = 100;
        for k = 1:length(Hilfsfeld)
            [xq ,yq] = bezier3(Layer_Punkte{k}.x, Layer_Punkte{k}.y,n);
            plot(app.UIAxes, xq, yq,farben_Abzweige(k));
            hold (app.UIAxes, 'on');
        end

        %Falls mehr abzweife gefunden werden
        if noFound>2
            plot(app.UIAxes, Hilfsfeld{2}.x, Hilfsfeld{2}.y, "Color",'g');
        end 


if app.Abzweig1Button==1 || app.Abzweig2Button==1 || app.Abzweig3Button==1 
        if app.Abzweig1Button.Value ==1
            abzweig=1;
            app.Abzweig1Button.Value =0;
            app.Abzweig2Button.Value =0;
            app.Abzweig3Button.Value =0;
        elseif app.Abzweig2Button.Value ==1
            abzweig=2;
            app.Abzweig1Button.Value =0;
            app.Abzweig2Button.Value =0;
            app.Abzweig3Button.Value =0;
        elseif app.Abzweig2Button.Value ==3
            abzweig=3;
            app.Abzweig1Button.Value =0;
            app.Abzweig2Button.Value =0;
            app.Abzweig3Button.Value =0;
        end

        bezier_sortiert{1,k+1}.x=Hilfsfeld{abzweig}.x;
        bezier_sortiert{1,k+1}.y=Hilfsfeld{abzweig}.y;
        Zwischenpunkt{1,1}= bezier_sortiert{1,k+1};
        eintrag= speicher{abzweig};

        %Einfärbungen rückgängig machen
        for i=1:noFound
            for k = 1:length(Hilfsfeld)
                [xq ,yq] = bezier3(Layer_Punkte{k}.x, Layer_Punkte{k}.y,n);
                plot(app.UIAxes, xq, yq,'k');
                hold (app.UIAxes, 'on');
            end
        end

        bezier_unsortiert{1,eintrag}.x=[];
        bezier_unsortiert{1,eintrag}.y=[];


        elseif noFound==0
            anzahl = num2str(f);
            disp('Keine Wert gefunden');
            disp(anzahl);
            f=f+1;

    end
    end
end


app.Fahrkurs =  bezier_sortiert;


        end

        % Button pushed function: ZwischenstandspeichernButton
        function ZwischenstandspeichernButtonPushed(app, event)
            save('Fahrkurs.mat', app.Fahrkurs)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Layout')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ZwischenstandspeichernButton = uibutton(app.UIFigure, 'push');
            app.ZwischenstandspeichernButton.ButtonPushedFcn = createCallbackFcn(app, @ZwischenstandspeichernButtonPushed, true);
            app.ZwischenstandspeichernButton.Text = 'Zwischenstand speichern ';
            app.DateipfadEditFieldLabel = uilabel(app.UIFigure);
            app.DateipfadEditFieldLabel.Text = 'Dateipfad';
            app.DateipfadEditField = uieditfield(app.UIFigure, 'text');
            app.DateipfadEditField.ValueChangingFcn = createCallbackFcn(app, @DateipfadEditFieldValueChanging, true);
            app.LayerAuswahlListBoxLabel = uilabel(app.UIFigure);
            app.LayerAuswahlListBoxLabel.Text = 'Layer Auswahl';
            app.LayerAuswahlListBox = uilistbox(app.UIFigure);
            app.LayerAuswahlListBox.Multiselect = 'on';
            app.LayerAuswahlListBox.ValueChangedFcn = createCallbackFcn(app, @LayerAuswahlListBoxValueChanged, true);
            app.LayerAuswahlListBox.Value = {'Item 1'};
            app.AbzweigButtonGroup = uibuttongroup(app.UIFigure);
            app.AbzweigButtonGroup.Title = 'Abzweig ';
            app.Abzweig1Button = uitogglebutton(app.AbzweigButtonGroup);
            app.Abzweig1Button.Text = 'Abzweig 1';
            app.Abzweig1Button.Value = true;
            app.Abzweig2Button = uitogglebutton(app.AbzweigButtonGroup);
            app.Abzweig2Button.Text = 'Abzweig 2';
            app.Abzweig3Button = uitogglebutton(app.AbzweigButtonGroup);
            app.Abzweig3Button.Text = 'Abzweig 3';
            app.SelectTrackLabel = uilabel(app.UIFigure);
            app.SelectTrackLabel.Text = 'Select Track';
            app.SourceDropDownLabel = uilabel(app.UIFigure);
            app.SourceDropDownLabel.Text = 'Source';
            app.SourceDropDown = uidropdown(app.UIFigure);
            app.SourceDropDown.Items = {'ANS+ Import', 'CAD Import'};
            app.SourceDropDown.Value = 'ANS+ Import';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2.';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3.';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Choose_Track_Huellkurve

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end