classdef Fahrzeugkinematik < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SaveAGVDataButton              matlab.ui.control.Button
        Label_3                        matlab.ui.control.Label
        AGVNameDropDown                matlab.ui.control.DropDown
        AGVNameDropDownLabel           matlab.ui.control.Label
        Label_2                        matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        SensortypDropDown              matlab.ui.control.DropDown
        SensortypDropDownLabel         matlab.ui.control.Label
        Image                          matlab.ui.control.Image
        GrundaufbauDropDown            matlab.ui.control.DropDown
        GrundaufbauDropDownLabel       matlab.ui.control.Label
        AuswahlFahrzeugkinematikLabel  matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Drop down opening function: GrundaufbauDropDown
        function GrundaufbauDropDownOpening(app, event)
            app.SaveAGVDataButton.Visible="off";
            load ("AGV_libary_neu.mat");
            type=[string(agv(1).type)];
            for i=1:length(agv)
                if ~any(strcmp(type, string(agv(i).type)))

                    type=[type , string(agv(i).type)];
                end
            end

         app.GrundaufbauDropDown.Items= type;

         type_selected=app.GrundaufbauDropDown.ValueChangedFcn;

        end

        % Image clicked function: Image
        function ImageClicked(app, event)
        
        end

        % Drop down opening function: SensortypDropDown
        function SensortypDropDownOpening(app, event)
            app.SaveAGVDataButton.Visible="off";
            load ("AGV_libary_neu.mat");
            sensor=[string(agv(1).sensor.type)];
            type_selected= app.GrundaufbauDropDown.Value;

            for i=1:length(agv)
                if ~any(strcmp(sensor, string(agv(i).sensor.type))) && any(strcmp(type_selected, string(agv(i).type)))

                    sensor=[sensor , string(agv(i).sensor.type)];
                end
            end

         app.SensortypDropDown.Items= sensor;

         sensor_selected=app.SensortypDropDown.Value;
        end

        % Drop down opening function: AGVNameDropDown
        function AGVNameDropDownOpening(app, event)
            app.SaveAGVDataButton.Visible="off";
            load ("AGV_libary_neu.mat");
            sensor_selected = app.SensortypDropDown.Value;
            type_selected= app.GrundaufbauDropDown.Value;

            name=[];
            for i=1:length(agv)
                if any(strcmp(sensor_selected, string(agv(i).sensor.type))) && any(strcmp(type_selected, string(agv(i).type)))
                    if isempty(name)
                        name=string(agv(i).name);
                    else
                        name=[name , string(agv(i).name)];
                    end
                end
            end

            app.AGVNameDropDown.Items= name;

            name_selected=app.AGVNameDropDown.Value;
            for i=1:length(agv)
                if strcmp(name_selected, string(agv(i).name))

                    agv_num=i;
                end
            end

            g.curMapPos(1)=0;
            g.curMapPos(2)=0;
            g.curMapPos(3)=0;

            cla(app.UIAxes,'reset')
            hold(app.UIAxes,'on')
            plotAGV(agv(agv_num),g,app.UIAxes);


            app.SaveAGVDataButton.Visible="on";

        end

        % Value changed function: GrundaufbauDropDown
        function GrundaufbauDropDownValueChanged(app, event)
            type_selected = app.GrundaufbauDropDown.Value;
            
        end

        % Button pushed function: SaveAGVDataButton
        function SaveAGVDataButtonPushed(app, event)
            %app.SaveAGVDataButton.Visible="on";
            name_selected=app.AGVNameDropDown.Value;


            load ("AGV_libary_neu.mat");

            for i=1:length(agv)
                if strcmp(name_selected, string(agv(i).name))

                    agv_num=i;
                end
            end
            agv_selected= agv(agv_num);
            save("AGV","agv_selected");

            %% Open next App
            %app.Choose_Track_Huellkurve.visible='on';
            %Choose_Track_Huellkurve.mlapp;
           

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'AGV')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.AuswahlFahrzeugkinematikLabel = uilabel(app.UIFigure);
            app.AuswahlFahrzeugkinematikLabel.Text = 'Auswahl - Fahrzeugkinematik';
            app.GrundaufbauDropDownLabel = uilabel(app.UIFigure);
            app.GrundaufbauDropDownLabel.Text = 'Grundaufbau';
            app.GrundaufbauDropDown = uidropdown(app.UIFigure);
            app.GrundaufbauDropDown.Items = {};
            app.GrundaufbauDropDown.DropDownOpeningFcn = createCallbackFcn(app, @GrundaufbauDropDownOpening, true);
            app.GrundaufbauDropDown.ValueChangedFcn = createCallbackFcn(app, @GrundaufbauDropDownValueChanged, true);
            app.GrundaufbauDropDown.Value = {};
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageClickedFcn = createCallbackFcn(app, @ImageClicked, true);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'Kinematik.PNG');
            app.SensortypDropDownLabel = uilabel(app.UIFigure);
            app.SensortypDropDownLabel.Text = 'Sensortyp';
            app.SensortypDropDown = uidropdown(app.UIFigure);
            app.SensortypDropDown.Items = {};
            app.SensortypDropDown.DropDownOpeningFcn = createCallbackFcn(app, @SensortypDropDownOpening, true);
            app.SensortypDropDown.Value = {};
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1.';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2.';
            app.AGVNameDropDownLabel = uilabel(app.UIFigure);
            app.AGVNameDropDownLabel.Text = 'AGV Name';
            app.AGVNameDropDown = uidropdown(app.UIFigure);
            app.AGVNameDropDown.Items = {};
            app.AGVNameDropDown.DropDownOpeningFcn = createCallbackFcn(app, @AGVNameDropDownOpening, true);
            app.AGVNameDropDown.Value = {};
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3.';
            app.SaveAGVDataButton = uibutton(app.UIFigure, 'push');
            app.SaveAGVDataButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAGVDataButtonPushed, true);
            app.SaveAGVDataButton.Text = 'Save AGV Data';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Fahrzeugkinematik

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end